classdef pet_app_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        SUVRTab                       matlab.ui.container.Tab
        ReferenceRegionforSUVRPanel   matlab.ui.container.Panel
        SelectReferenceRegionListBox  matlab.ui.control.ListBox
        PartialVolumeCorrectionPanel  matlab.ui.container.Panel
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        FWHMEditField                 matlab.ui.control.NumericEditField
        FWHMEditFieldLabel            matlab.ui.control.Label
        PETScannerDropDown            matlab.ui.control.DropDown
        GTMButton                     matlab.ui.control.RadioButton
        MullerGartnerButton           matlab.ui.control.RadioButton
        PVCSwitch                     matlab.ui.control.ToggleSwitch
        AlignTab                      matlab.ui.container.Tab
        CoregisterTab                 matlab.ui.container.Tab
        PVCTab                        matlab.ui.container.Tab
        SurfaceTab                    matlab.ui.container.Tab
        NextButton                    matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ParallelProcessingPanel       matlab.ui.container.Panel
        ParallelSwitch                matlab.ui.control.ToggleSwitch
        CoresSpinner                  matlab.ui.control.Spinner
        CoresSpinnerLabel             matlab.ui.control.Label
        HTML                          matlab.ui.control.HTML
        BrainSuiteLogo                matlab.ui.control.Image
        FreeSurferLogo                matlab.ui.control.Image
        SelectDropDown                matlab.ui.control.DropDown
        Panel_2                       matlab.ui.container.Panel
        ProcessButton                 matlab.ui.control.Button
        PETProcessingUtilityLabel     matlab.ui.control.Label
        BrainstormBanner              matlab.ui.control.Image
    end


    properties (Access = public)
        LabelNames % Description
        Tracer
        ReferenceRegion
        FWHM
        Threads
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            value = app.SelectDropDown.Value;
            if strcmpi(value,'BrainSuite')
                app.BrainSuiteLogo.Visible="on";
                app.FreeSurferLogo.Visible="off";
           
        elseif strcmpi(value, 'FreeSurfer');
                app.FreeSurferLogo.Visible="on";
                app.BrainSuiteLogo.Visible="off";
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ParallelSwitch
        function ParallelSwitchValueChanged(app, event)
            value = app.ParallelSwitch.Value;
            if value
                app.CoresSpinner.Enable="on";
                app.CoresSpinnerLabel.Enable="on";
            else
                app.CoresSpinner.Enable="off"';
                app.CoresSpinnerLabel.Enable="off";
            end
        end

        % Value changed function: PVCSwitch
        function PVCSwitchValueChanged(app, event)
            value = app.PVCSwitch.Value;
            if strcmp(value, 'On')
                app.FWHMEditField.Enable="on";
                app.FWHMEditFieldLabel.Enable="on";
                app.MethodButtonGroup.Enable="on";
            end
            if strcmp(value, 'Off')
                app.FWHMEditField.Enable="off";
                app.FWHMEditFieldLabel.Enable="off";
                app.MethodButtonGroup.Enable="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BrainstormBanner = uiimage(app.Panel_2);
            app.BrainstormBanner.URL = 'https://neuroimage.usc.edu/brainstorm/Introduction';
            app.BrainstormBanner.ImageSource = fullfile(pathToMLAPP, 'assets', 'brainstorm logo.png');
            app.PETProcessingUtilityLabel = uilabel(app.Panel_2);
            app.PETProcessingUtilityLabel.Text = 'PET Processing Utility';
            app.ProcessButton = uibutton(app.Panel_2, 'push');
            app.ProcessButton.Text = 'Process';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.Panel_3);
            app.SelectDropDown = uidropdown(app.Panel);
            app.SelectDropDown.Items = {'BrainSuite', 'FreeSurfer'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Tooltip = {'Select method'};
            app.SelectDropDown.Value = 'FreeSurfer';
            app.FreeSurferLogo = uiimage(app.Panel);
            app.FreeSurferLogo.URL = 'https://surfer.nmr.mgh.harvard.edu/fswiki/PetSurfer';
            app.FreeSurferLogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'freesufer_logoHR.png');
            app.BrainSuiteLogo = uiimage(app.Panel);
            app.BrainSuiteLogo.Visible = 'off';
            app.BrainSuiteLogo.URL = 'https://brainsuite.org/';
            app.BrainSuiteLogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'brainsuite_logo.png');
            app.HTML = uihtml(app.Panel);
            app.HTML.HTMLSource = '<!DOCTYPE html><html><head><style>body {    text-align: center;}button {    width: 40px;    height: 18px;}progress {    width: 80%;    height: 15px;}</style><script type="text/javascript">          function setup(htmlComponent) {        let btn = document.getElementById("nextButton");        htmlComponent.Data = 0;        btn.addEventListener("click", function(event) {            let progress = htmlComponent.Data;            progress = Math.min(progress+25,100);            document.getElementById("progressBar").value = progress;            htmlComponent.Data = progress;        });     }</script></head><body>    <button id="nextButton">Next</button>    <br>    <progress id="progressBar" value="0" max="100"></progress> </body>	</html>';
            app.ParallelProcessingPanel = uipanel(app.Panel);
            app.ParallelProcessingPanel.Title = 'Parallel Processing';
            app.CoresSpinnerLabel = uilabel(app.ParallelProcessingPanel);
            app.CoresSpinnerLabel.Enable = 'off';
            app.CoresSpinnerLabel.Text = 'Cores';
            app.CoresSpinner = uispinner(app.ParallelProcessingPanel);
            app.CoresSpinner.Enable = 'off';
            app.ParallelSwitch = uiswitch(app.ParallelProcessingPanel, 'toggle');
            app.ParallelSwitch.ValueChangedFcn = createCallbackFcn(app, @ParallelSwitchValueChanged, true);
            app.TabGroup = uitabgroup(app.Panel_3);
            app.SUVRTab = uitab(app.TabGroup);
            app.SUVRTab.Title = 'SUVR';
            app.PartialVolumeCorrectionPanel = uipanel(app.SUVRTab);
            app.PartialVolumeCorrectionPanel.Title = 'Partial Volume Correction';
            app.PVCSwitch = uiswitch(app.PartialVolumeCorrectionPanel, 'toggle');
            app.PVCSwitch.ValueChangedFcn = createCallbackFcn(app, @PVCSwitchValueChanged, true);
            app.MethodButtonGroup = uibuttongroup(app.PartialVolumeCorrectionPanel);
            app.MethodButtonGroup.Enable = 'off';
            app.MethodButtonGroup.Title = 'Method';
            app.MullerGartnerButton = uiradiobutton(app.MethodButtonGroup);
            app.MullerGartnerButton.Text = 'Muller-Gartner';
            app.MullerGartnerButton.Value = true;
            app.GTMButton = uiradiobutton(app.MethodButtonGroup);
            app.GTMButton.Text = 'GTM';
            app.PETScannerDropDown = uidropdown(app.MethodButtonGroup);
            app.PETScannerDropDown.Items = {'GE Discovery 600, 610, 690, 710, and MI DR - PET/CT ', 'GE Discovery 690-XT - PET/CT ', 'GE Discovery MI', 'GE Discovery RX ', 'GE Discovery ST', 'GE Discovery STE', 'GE Omni Legend', 'GE Signa ', 'Philips Gemini TF', 'Philips Ingenuity TF ', 'Philips Vereos', 'Siemens BioGraph HiRes (Model 1080) ', 'Siemens BioGraph Horizon ', 'Siemens BioGraph mCT', 'Siemens BioGraph mMR', 'Siemens BioGraph TruePoint (Models 1093, 1094)', 'Siemens BioGraph Vision', 'Siemens ECAT Exact HR+', 'Siemens ECAT High Resolution Research Tomograph'};
            app.PETScannerDropDown.Value = 'Siemens ECAT High Resolution Research Tomograph';
            app.FWHMEditFieldLabel = uilabel(app.MethodButtonGroup);
            app.FWHMEditFieldLabel.Enable = 'off';
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.MethodButtonGroup, 'numeric');
            app.FWHMEditField.Enable = 'off';
            app.FWHMEditField.Value = 2.8;
            app.ReferenceRegionforSUVRPanel = uipanel(app.SUVRTab);
            app.ReferenceRegionforSUVRPanel.Title = 'Reference Region for SUVR';
            app.SelectReferenceRegionListBox = uilistbox(app.ReferenceRegionforSUVRPanel);
            app.SelectReferenceRegionListBox.Items = {'Cerebellum', 'Cerebellum - GM', 'Pons', 'Left-Cerebral-Exterior', 'Left-Cerebral-White-Matter', 'Left-Cerebral-Cortex', 'Left-Lateral-Ventricle', 'Left-Inf-Lat-Vent', 'Left-Cerebellum-Exterior', 'Left-Cerebellum-White-Matter', 'Left-Cerebellum-Cortex', 'Left-Thalamus-unused', 'Left-Thalamus', 'Left-Caudate', 'Left-Putamen', 'Left-Pallidum', '3rd-Ventricle', '4th-Ventricle', 'Brain-Stem', 'Left-Hippocampus', 'Left-Amygdala', 'Left-Insula', 'Left-Operculum', 'Line-1', 'Line-2', 'Line-3', 'CSF', 'Left-Lesion', 'Left-Accumbens-area', 'Left-Substancia-Nigra', 'Left-VentralDC', 'Left-undetermined', 'Left-vessel', 'Left-choroid-plexus', 'Left-F3orb', 'Left-aOg', 'Left-WMCrowns', 'Left-mOg', 'Left-pOg', 'Left-Stellate', 'Left-Porg', 'Left-Aorg', 'Right-Cerebral-Exterior', 'Right-Cerebral-White-Matter', 'Right-Cerebral-Cortex', 'Right-Lateral-Ventricle', 'Right-Inf-Lat-Vent', 'Right-Cerebellum-Exterior', 'Right-Cerebellum-White-Matter', 'Right-Cerebellum-Cortex', 'Right-Thalamus-unused', 'Right-Thalamus', 'Right-Caudate', 'Right-Putamen', 'Right-Pallidum', 'Right-Hippocampus', 'Right-Amygdala', 'Right-Insula', 'Right-Operculum', 'Right-Lesion', 'Right-Accumbens-area', 'Right-Substancia-Nigra', 'Right-VentralDC', 'Right-undetermined', 'Right-vessel', 'Right-choroid-plexus', 'Right-F3orb', 'Right-lOg', 'Right-WMCrowns', 'Right-mOg', 'Right-pOg', 'Right-Stellate', 'Right-Porg', 'Right-Aorg', '5th-Ventricle', 'Left-Interior', 'Right-Interior', 'Left-Locus-Coeruleus', 'Right-Locus-Coeruleus', 'WM-hypointensities', 'Left-WM-hypointensities', 'Right-WM-hypointensities', 'non-WM-hypointensities', 'Left-non-WM-hypointensities', 'Right-non-WM-hypointensities', 'Left-F1', 'Right-F1', 'Optic-Chiasm', 'Corpus_Callosum', 'Left_future_WMSA', 'Right_future_WMSA', 'future_WMSA', 'Left-Amygdala-Anterior', 'Right-Amygdala-Anterior', 'Dura', 'Lesion', 'Left-wm-intensity-abnormality', 'Left-caudate-intensity-abnormality', 'Left-putamen-intensity-abnormality', 'Left-accumbens-intensity-abnormality', 'Left-pallidum-intensity-abnormality', 'Left-amygdala-intensity-abnormality', 'Left-hippocampus-intensity-abnormality', 'Left-thalamus-intensity-abnormality', 'Left-VDC-intensity-abnormality', 'Right-wm-intensity-abnormality', 'Right-caudate-intensity-abnormality', 'Right-putamen-intensity-abnormality', 'Right-accumbens-intensity-abnormality', 'Right-pallidum-intensity-abnormality', 'Right-amygdala-intensity-abnormality', 'Right-hippocampus-intensity-abnormality', 'Right-thalamus-intensity-abnormality', 'Right-VDC-intensity-abnormality', 'Epidermis', 'Conn-Tissue', 'SC-Fat-Muscle', 'Cranium', 'CSF-SA', 'Muscle', 'Ear', 'Adipose', 'Spinal-Cord', 'Soft-Tissue', 'Nerve', 'Bone', 'AirCavity', 'Orbital-Fat', 'Tongue', 'Nasal-Structures', 'Globe', 'Teeth', 'Left-Caudate-Putamen', 'Right-Caudate-Putamen', 'Left-Claustrum', 'Right-Claustrum', 'Cornea', 'Diploe', 'Vitreous-Humor', 'Lens', 'Aqueous-Humor', 'Outer-Table', 'Inner-Table', 'Periosteum', 'Endosteum', 'R-C-S', 'Iris', 'SC-Adipose-Muscle', 'SC-Tissue', 'Orbital-Adipose', 'Left-IntCapsule-Ant', 'Right-IntCapsule-Ant', 'Left-IntCapsule-Pos', 'Right-IntCapsule-Pos'};
            app.SelectReferenceRegionListBox.ItemsData = {'8 9 47 48', '8 47', '174', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '77', '78', '79', '80', '81', '82', '83', '84', '85', '96', '97', '98', '100', '101', '102', '103', '104', '105', '106', '107', '108', '109', '110', '111', '112', '113', '114', '115', '116', '117', '118', '119', '120', '121', '122', '123', '124', '125', '126', '127', '128', '129', '130', '131', '132', '133', '134', '135', '136', '137', '138', '139', '140', '142', '143', '144', '145', '146', '147', '148', '149', '150', '151', '152', '153', '154', '155', '156', '157', '158', '159', '160', '161', '162', '163', '164', '165', '166', '167', '168', '169', '176', '170', '171', '172', '173', '174', '175', '177', '178', '179', '180', '181', '182', '193', '194', '195', '196', '197', '198', '199', '200', '201', '202', '203', '204', '205', '206', '207', '208', '209', '210', '211', '212', '213', '214', '215', '216', '217', '218', '219', '220', '221', '222', '223', '224', '225', '226', '250', '251', '252', '253', '254', '255', '1000', '1001', '1002', '1003', '1004', '1005', '1006', '1007', '1008', '1009', '1010', '1011', '1012', '1013', '1014', '1015', '1016', '1017', '1018', '1019', '1020', '1021', '1022', '1023', '1024', '1025', '1026', '1027', '1028', '1029', '1030', '1031', '1032', '1033', '1034', '1035', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018', '2019', '2020', '2021', '2022', '2023', '2024', '2025', '2026', '2027', '2028', '2029', '2030', '2031', '2032', '2033', '2034', '2035', '11100', '11101', '11102', '11103', '11104', '11105', '11106', '11107', '11108', '11109', '11110', '11111', '11112', '11113', '11114', '11115', '11116', '11117', '11118', '11119', '11120', '11121', '11122', '11123', '11124', '11125', '11126', '11127', '11128', '11129', '11130', '11131', '11132', '11133', '11134', '11135', '11136', '11137', '11138', '11139', '11140', '11141', '11142', '11143', '11144', '11145', '11146', '11147', '11148', '11149', '11150', '11151', '11152', '11153', '11154', '11155', '11156', '11157', '11158', '11159', '11160', '11161', '11162', '11163', '11164', '11165', '11166', '11167', '11168', '11169', '11170', '11171', '11172', '11173', '11174', '11175', '12100', '12101', '12102', '12103', '12104', '12105', '12106', '12107', '12108', '12109', '12110', '12111', '12112', '12113', '12114', '12115', '12116', '12117', '12118', '12119', '12120', '12121', '12122', '12123', '12124', '12125', '12126', '12127', '12128', '12129', '12130', '12131', '12132', '12133', '12134', '12135', '12136', '12137', '12138', '12139', '12140', '12141', '12142', '12143', '12144', '12145', '12146', '12147', '12148', '12149', '12150', '12151', '12152', '12153', '12154', '12155', '12156', '12157', '12158', '12159', '12160', '12161', '12162', '12163', '12164', '12165', '12166', '12167', '12168', '12169', '12170', '12171', '12172', '12173', '12174', '12175'};
            app.SelectReferenceRegionListBox.Multiselect = 'on';
            app.SelectReferenceRegionListBox.Value = {'8 9 47 48'};
            app.AlignTab = uitab(app.TabGroup);
            app.AlignTab.Title = 'Align';
            app.CoregisterTab = uitab(app.TabGroup);
            app.CoregisterTab.Title = 'Co-register';
            app.PVCTab = uitab(app.TabGroup);
            app.PVCTab.Title = 'PVC';
            app.SurfaceTab = uitab(app.TabGroup);
            app.SurfaceTab.Title = 'Surface';
            app.BackButton = uibutton(app.SurfaceTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.SurfaceTab, 'push');
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pet_app_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end